classdef Q1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AddPanel                 matlab.ui.container.Panel
        AddButton                matlab.ui.control.Button
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        ImaginaryEditFieldLabel  matlab.ui.control.Label
        ImaginaryEditField       matlab.ui.control.NumericEditField
        RealLabel                matlab.ui.control.Label
        RealEditField            matlab.ui.control.NumericEditField
        PloysettingButtonGroup   matlab.ui.container.ButtonGroup
        MagnitudelinearButton    matlab.ui.control.RadioButton
        MagnitudedBButton        matlab.ui.control.RadioButton
        PhaseargButton           matlab.ui.control.RadioButton
        PhaseArgButton           matlab.ui.control.RadioButton
        InformationPanel         matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        NumZerosLabel            matlab.ui.control.Label
        NumofPolesLabel          matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        poles % Description
        zeros % Description
        counter % Description
        Property4 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            a = app.RealEditField.Value ;
            b = app.ImaginaryEditField.Value ;
            type = app.TypeDropDown.Value ;
            if str2num(app.Label_2.Text) == 0
                    app.zeros = 1 ;
            end
            
            if str2num(app.Label.Text) == 0
                    app.poles = 1 ;
            end
            
            switch type
                case 'Pole'
                    r = abs(a + 1i * b) ;
                    theta = atan2 (b , a);
                    
                    app.poles = conv(app.poles , [1 , -r*exp(1i*theta)] ) ;
                    app.Label.Text = num2str ( str2num(app.Label.Text) + 1 ) ;
                    
                case 'Zero'
                    z = a + 1i * b ;
                    r = abs(a + 1i * b) ;
                    theta = atan2 (b , a);
                    
                    app.zeros = conv(app.zeros , [1 , -r*exp(1i*theta)] ) ;
                    app.Label_2.Text = num2str ( str2num(app.Label_2.Text) + 1 ) ;
            end
            app.PloysettingButtonGroupSelectionChanged ;
        end

        % Selection changed function: PloysettingButtonGroup
        function PloysettingButtonGroupSelectionChanged(app, event)
            selectedButton = app.PloysettingButtonGroup.SelectedObject.Text ;
            
            [h , w ] = freqz(app.zeros , app.poles , 1000 , 'whole') ;
            mag = abs(h) ;
            Phase = phase(h) ;
            db = 20 * log10(mag) ;

            switch selectedButton
                case 'Magnitude (linear)'
                    plot(app.UIAxes, w/pi , mag ) ;
                    app.UIAxes.YLabel = text(app.UIAxes,0,0,'Magnitude (linear)') ;
                    app.UIAxes.XLabel= text(app.UIAxes,0,0,'Normalized Frequency (\times\pi rad )') ;
                    app.UIAxes.Title = text(app.UIAxes,0,0,'Frq. Response (Mag. linear)') ;
                    app.UIAxes.XGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
                    app.UIAxes.YGrid = 'on'; app.UIAxes.YMinorGrid = 'on';
                    
                case 'Magnitude (dB)'
                    plot(app.UIAxes, w/pi , db ) ;
                    app.UIAxes.YLabel = text(app.UIAxes,0,0,'Magnitude (dB)') ;
                    app.UIAxes.XLabel= text(app.UIAxes,0,0,'Normalized Frequency (\times\pi rad )') ;
                    app.UIAxes.Title = text(app.UIAxes,0,0,'Frq. Response (Mag. dB)') ;
                    app.UIAxes.XGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
                    app.UIAxes.YGrid = 'on'; app.UIAxes.YMinorGrid = 'on';
                    
                case 'Phase (arg)'
                    plot(app.UIAxes, w/pi , Phase) ;
                    app.UIAxes.YLabel = text(app.UIAxes,0,0,'Phase (arg) (radian)') ;
                    app.UIAxes.XLabel= text(app.UIAxes,0,0,'Normalized Frequency (\times\pi rad )') ;
                    app.UIAxes.Title = text(app.UIAxes,0,0,'Frq. Response (Phase unwrapped)') ;
                    app.UIAxes.XGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
                    app.UIAxes.YGrid = 'on'; app.UIAxes.YMinorGrid = 'on';
                    
                case 'Phase (Arg)'
                    plot(app.UIAxes, w/pi , angle(h)) ;
                    app.UIAxes.YLabel = text(app.UIAxes,0,0,'Phase (Arg) (radian)') ;
                    app.UIAxes.XLabel= text(app.UIAxes,0,0,'Normalized Frequency (\times\pi rad )') ;
                    app.UIAxes.Title = text(app.UIAxes,0,0,'Frq. Response (Phase wrapped)') ;
                    app.UIAxes.XGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
                    app.UIAxes.YGrid = 'on'; app.UIAxes.YMinorGrid = 'on';
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddPanel = uipanel(app.UIFigure);
            app.AddPanel.Title = 'Add';
            app.AddButton = uibutton(app.AddPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.TypeDropDownLabel = uilabel(app.AddPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.AddPanel);
            app.TypeDropDown.Items = {'Pole', 'Zero'};
            app.TypeDropDown.Value = 'Pole';
            app.ImaginaryEditFieldLabel = uilabel(app.AddPanel);
            app.ImaginaryEditFieldLabel.Text = 'Imaginary';
            app.ImaginaryEditField = uieditfield(app.AddPanel, 'numeric');
            app.RealLabel = uilabel(app.AddPanel);
            app.RealLabel.Text = 'Real';
            app.RealEditField = uieditfield(app.AddPanel, 'numeric');
            app.PloysettingButtonGroup = uibuttongroup(app.UIFigure);
            app.PloysettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PloysettingButtonGroupSelectionChanged, true);
            app.PloysettingButtonGroup.Title = 'Ploy setting';
            app.MagnitudelinearButton = uiradiobutton(app.PloysettingButtonGroup);
            app.MagnitudelinearButton.Text = 'Magnitude (linear)';
            app.MagnitudelinearButton.Value = true;
            app.MagnitudedBButton = uiradiobutton(app.PloysettingButtonGroup);
            app.MagnitudedBButton.Text = 'Magnitude (dB)';
            app.PhaseargButton = uiradiobutton(app.PloysettingButtonGroup);
            app.PhaseargButton.Text = 'Phase (arg)';
            app.PhaseArgButton = uiradiobutton(app.PloysettingButtonGroup);
            app.PhaseArgButton.Text = 'Phase (Arg)';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.Label = uilabel(app.InformationPanel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.InformationPanel);
            app.Label_2.Text = '0';
            app.NumZerosLabel = uilabel(app.InformationPanel);
            app.NumZerosLabel.Text = 'Num. Zeros';
            app.NumofPolesLabel = uilabel(app.InformationPanel);
            app.NumofPolesLabel.Text = 'Num. of Poles';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Freq. Response')
            xlabel(app.UIAxes, 'Normalized Frequency (\times\pi rad/sample)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end