classdef Q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DataPanel                matlab.ui.container.Panel
        alphaEditFieldLabel      matlab.ui.control.Label
        alphaEditField           matlab.ui.control.NumericEditField
        NEditFieldLabel          matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        MEditFieldLabel          matlab.ui.control.Label
        MEditField               matlab.ui.control.NumericEditField
        PlotButtonGroup          matlab.ui.container.ButtonGroup
        TimeDomainButton         matlab.ui.control.RadioButton
        FreqDomainButton         matlab.ui.control.RadioButton
        PlotButton               matlab.ui.control.Button
        AudioPlayerPanel         matlab.ui.container.Panel
        PlayOrginialVoiceButton  matlab.ui.control.Button
        PlayEchoedVoiceButton    matlab.ui.control.Button
        OpenTheVoiceButton       matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        alpha % Description
        N % Description
        M % Description
        audio % Description
        fs % Description
        echo % Description
    end
    
    methods (Access = public)
        
        function result = shift(app,x,k)
            l = length(x) ;
            
            for i = 1 : l
                g(i + k) = x(i) ;
            end
            result = g ;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)

            app.alpha = app.alphaEditField.Value ;
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)

            app.N = app.NEditField.Value;
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)

            app.M = app.MEditField.Value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.alphaEditFieldValueChanged ;
            app.NEditFieldValueChanged ;
            app.MEditFieldValueChanged ;
            
            L = length(app.audio) ;
            app.echo = zeros(1 , (app.N * app.M) + L ) ;
                      
            for k = 0 : app.N
                app.echo = app.echo +  (app.alpha)^k  .* [shift(app,app.audio , k*app.M ), zeros(1 ,(app.N*app.M)-(k*app.M) )] ;
            end
            
            z = app.PlotButtonGroup.SelectedObject.Text ;
            t1 = 0 : 1/app.fs : (length(app.audio)/app.fs)- 1/app.fs ;
            t2 = 0 : 1/app.fs : (length(app.echo)/app.fs)- 1/app.fs ;
            switch z
                case 'Time Domain'
                    plot(app.UIAxes,t1,app.audio) ;
                    app.UIAxes.Title = text(app.UIAxes,0,0,'Original Audio In Time Domain');
                    app.UIAxes.XLabel= text(app.UIAxes,0,0,'Time ( second )');
                    app.UIAxes.YLabel= text(app.UIAxes,0,0,'Amplitude');
                    app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
                    app.UIAxes.YMinorGrid = 'on';
                    
                    plot(app.UIAxes2,t2,app.echo) ;          
                    app.UIAxes2.Title = text(app.UIAxes2,0,0,'Echoed Audio In Time Domain');
                    app.UIAxes2.XLabel= text(app.UIAxes2,0,0,'Time ( second )');
                    app.UIAxes2.YLabel= text(app.UIAxes2,0,0,'Amplitude');
                    app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on'; app.UIAxes2.XMinorGrid = 'on';
                    app.UIAxes2.YMinorGrid = 'on';
                     
                    
                case 'Freq. Domain'
                    [h1,w1] = freqz(app.audio,1000,'whole') ; 
                    mag1 = abs(h1) ;
                    db1 = 20*log10(mag1+eps) ;
                    plot(app.UIAxes,w1/pi , db1) ;
                    app.UIAxes.Title = text(app.UIAxes,0,0,'ّFrquency response Of Orginal Audio');
                    app.UIAxes.XLabel= text(app.UIAxes,0,0,'Normalized Frequency (\times\pi rad/sample)');
                    app.UIAxes.YLabel= text(app.UIAxes,0,0,'Magnitude (dB)');
                    app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
                    app.UIAxes.YMinorGrid = 'on';
                    
                    [h2,w2] = freqz(app.echo,1000,'whole') ; 
                    mag2 = abs(h2) ;
                    db2 = 20*log10(mag2+eps) ;
                    plot(app.UIAxes2, w2/pi , db2) ;
                    app.UIAxes2.Title = text(app.UIAxes2,0,0,'Frquency response Of Echoed Audio');
                    app.UIAxes2.XLabel= text(app.UIAxes2,0,0,'Normalized Frequency (\times\pi rad/sample)');
                    app.UIAxes2.YLabel= text(app.UIAxes2,0,0,'Magnitude (dB)');
                    app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on'; app.UIAxes2.XMinorGrid = 'on';
                    app.UIAxes2.YMinorGrid = 'on';
                            
            end
            
     
        end

        % Button pushed function: OpenTheVoiceButton
        function OpenTheVoiceButtonPushed(app, event)
            
             [file,path] = uigetfile('*.wav','Select The Audio file (.wav) ') ;
            [app.audio , app.fs ] = audioread(fullfile(path,file))  ;
            app.audio = (app.audio)' ;
        
        end

        % Button pushed function: PlayOrginialVoiceButton
        function PlayOrginialVoiceButtonPushed(app, event)
            sound(app.audio,app.fs) ; 
        end

        % Button pushed function: PlayEchoedVoiceButton
        function PlayEchoedVoiceButtonPushed(app, event)
            sound(app.echo,app.fs) ; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.alphaEditFieldLabel = uilabel(app.DataPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.DataPanel, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.alphaEditField.Value = 0.7;
            app.NEditFieldLabel = uilabel(app.DataPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.DataPanel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 3;
            app.MEditFieldLabel = uilabel(app.DataPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.DataPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 44100;
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.Title = 'Plot';
            app.TimeDomainButton = uiradiobutton(app.PlotButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.TimeDomainButton.Value = true;
            app.FreqDomainButton = uiradiobutton(app.PlotButtonGroup);
            app.FreqDomainButton.Text = 'Freq. Domain';
            app.PlotButton = uibutton(app.PlotButtonGroup, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AudioPlayerPanel = uipanel(app.UIFigure);
            app.AudioPlayerPanel.Title = 'Audio Player';
            app.PlayOrginialVoiceButton = uibutton(app.AudioPlayerPanel, 'push');
            app.PlayOrginialVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOrginialVoiceButtonPushed, true);
            app.PlayOrginialVoiceButton.Text = 'Play Orginial Voice';
            app.PlayEchoedVoiceButton = uibutton(app.AudioPlayerPanel, 'push');
            app.PlayEchoedVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @PlayEchoedVoiceButtonPushed, true);
            app.PlayEchoedVoiceButton.Text = 'Play Echoed Voice';
            app.OpenTheVoiceButton = uibutton(app.AudioPlayerPanel, 'push');
            app.OpenTheVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTheVoiceButtonPushed, true);
            app.OpenTheVoiceButton.Text = 'Open The Voice';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end