classdef Q3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        HashTab                     matlab.ui.container.Tab
        OpenAudioFrequencyInvertionPanel  matlab.ui.container.Panel
        SaveHashedAudioButton       matlab.ui.control.Button
        OpenAudioFileButton         matlab.ui.control.Button
        CuttoffkhzEditFieldLabel    matlab.ui.control.Label
        CuttoffkhzEditField         matlab.ui.control.NumericEditField
        HashAudioButton             matlab.ui.control.Button
        PlayAudioPanel              matlab.ui.container.Panel
        InputAudioButton            matlab.ui.control.Button
        ScrambeledAudioButton       matlab.ui.control.Button
        SpectrumDiagramsPanel       matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        ReconstructionTab           matlab.ui.container.Tab
        OpenScarmbeledAudioReconstructionPanel  matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        OpenHashAudioButton         matlab.ui.control.Button
        CuttoffkhzEditField_2Label  matlab.ui.control.Label
        CuttoffkhzEditField_2       matlab.ui.control.NumericEditField
        ReconstructAudioButton      matlab.ui.control.Button
        PlayAudioPanel_2            matlab.ui.container.Panel
        InputScarmbeledAudioButton  matlab.ui.control.Button
        ReconstructedAudioButton    matlab.ui.control.Button
        SpectrumDiagramsPanel_2     matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        audio % Description
        fs % Description
        fc % Description
        scrambeled % Description
        hashed % Description
        fshashed % Description
        fcreconstruction % Description
        reconstructed % Description
        path % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            
            [file,path] = uigetfile('*.wav','Select The Audio file (.wav) ') ;
            [app.audio , app.fs ] = audioread(fullfile(path,file))  ;
            app.audio = (app.audio)' ;
            app.path = path ;
            
        end

        % Button pushed function: HashAudioButton
        function HashAudioButtonPushed(app, event)
        
            Yo = lowpass(app.audio , app.fc , app.fs ) ;
            t = 0 : 1/app.fs : length(app.audio)/app.fs - (1/app.fs) ;
            Y1 = Yo .* cos (2 * pi * app.fc * t) ;
            app.scrambeled = lowpass(Y1 , app.fc , app.fs ) ;
            
            [pxx1,w1] = periodogram(app.audio,[],4096,app.fs,'power','centered');
            plot(app.UIAxes,w1, pxx1) ;
            
            app.UIAxes.Title = text(app.UIAxes,0,0,'Input Spectrum');
            app.UIAxes.XLabel= text(app.UIAxes,0,0,'Normalized Frequency (Hz)');
            app.UIAxes.YLabel= text(app.UIAxes,0,0,'Freq. Response (Mag.) ');
            app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on'; app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            
            [pxx2,w2] = periodogram(app.scrambeled,[],4096,app.fs,'power','centered');
            plot(app.UIAxes2,w2, pxx2) ;
            app.UIAxes2.Title = text(app.UIAxes2,0,0,'Inverted Frequency Spectrum');
            app.UIAxes2.XLabel= text(app.UIAxes2,0,0,'Normalized Frequency (Hz)');
            app.UIAxes2.YLabel= text(app.UIAxes2,0,0,'Freq. Response (Mag.) ');
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on'; app.UIAxes2.XMinorGrid = 'on';
            app.UIAxes2.YMinorGrid = 'on';


        end

        % Button pushed function: SaveHashedAudioButton
        function SaveHashedAudioButtonPushed(app, event)
% 'C:\Users\Asus\Desktop\HashedAudio.wav'
            audiowrite(fullfile(app.path,'hashedAudio.wav'),app.scrambeled,app.fs ) ;
            
        end

        % Button pushed function: InputAudioButton
        function InputAudioButtonPushed(app, event)
            sound(app.audio , app.fs) ;
        end

        % Button pushed function: ScrambeledAudioButton
        function ScrambeledAudioButtonPushed(app, event)
            sound(app.scrambeled , app.fs) ;
        end

        % Button pushed function: OpenHashAudioButton
        function OpenHashAudioButtonPushed(app, event)
            
            
            [file,path] = uigetfile('*.wav','Select The Audio file (.wav) ') ;
            [app.hashed , app.fshashed ] = audioread(fullfile(path,file))  ;
            app.hashed = (app.hashed)' ;
            
            [pxx1,w1] = periodogram(app.hashed,[],4096,app.fshashed,'power','centered');
            plot(app.UIAxes_2,w1, pxx1) ;
            
            app.UIAxes_2.Title = text(app.UIAxes_2,0,0,'Input Freq. Inverted Spectrum');
            app.UIAxes_2.XLabel= text(app.UIAxes_2,0,0,'Normalized Frequency (Hz)');
            app.UIAxes_2.YLabel= text(app.UIAxes_2,0,0,'Freq. Response (Mag.) ');
            app.UIAxes_2.XGrid = 'on'; app.UIAxes_2.YGrid = 'on'; app.UIAxes_2.XMinorGrid = 'on';
            app.UIAxes_2.YMinorGrid = 'on';


        end

        % Value changed function: CuttoffkhzEditField_2
        function CuttoffkhzEditField_2ValueChanged(app, event)
            app.fcreconstruction = app.CuttoffkhzEditField_2.Value * 1000 ;
            
        end

        % Button pushed function: InputScarmbeledAudioButton
        function InputScarmbeledAudioButtonPushed(app, event)
            sound(app.hashed , app.fshashed) ;
        end

        % Button pushed function: ReconstructAudioButton
        function ReconstructAudioButtonPushed(app, event)
            
            
            Yo = lowpass(app.hashed , app.fcreconstruction , app.fshashed ) ;
            t = 0 : 1/app.fshashed : length(app.hashed)/app.fshashed - (1/app.fshashed) ;
            Y1 = Yo .* cos (2 * pi * app.fcreconstruction * t) ;
            app.reconstructed = lowpass(Y1 , app.fcreconstruction , app.fshashed ) * 2 ;
            
            
            [pxx2,w2] = periodogram(app.reconstructed,[],4096,app.fshashed,'power','centered');
            plot(app.UIAxes2_2,w2, pxx2) ;
            app.UIAxes2_2.Title = text(app.UIAxes2_2,0,0,'Reconstructed Spectrum');
            app.UIAxes2_2.XLabel= text(app.UIAxes2_2,0,0,'Normalized Frequency (Hz)');
            app.UIAxes2_2.YLabel= text(app.UIAxes2_2,0,0,'Freq. Response (Mag.) ');
            app.UIAxes2_2.XGrid = 'on'; app.UIAxes2_2.YGrid = 'on'; app.UIAxes2_2.XMinorGrid = 'on';
            app.UIAxes2_2.YMinorGrid = 'on';


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            audiowrite(fullfile(app.path,'reconstructedAudio.wav'),app.reconstructed,app.fshashed ) ;


        end

        % Value changed function: CuttoffkhzEditField
        function CuttoffkhzEditFieldValueChanged(app, event)
           app.fc = app.CuttoffkhzEditField.Value*1000;
            
        end

        % Button pushed function: ReconstructedAudioButton
        function ReconstructedAudioButtonPushed(app, event)
            sound(app.reconstructed , app.fshashed) ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HashTab = uitab(app.TabGroup);
            app.HashTab.Title = 'Hash';
            app.OpenAudioFrequencyInvertionPanel = uipanel(app.HashTab);
            app.OpenAudioFrequencyInvertionPanel.Title = 'Open Audio & Frequency Invertion';
            app.SaveHashedAudioButton = uibutton(app.OpenAudioFrequencyInvertionPanel, 'push');
            app.SaveHashedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveHashedAudioButtonPushed, true);
            app.SaveHashedAudioButton.Text = 'Save Hashed Audio';
            app.OpenAudioFileButton = uibutton(app.OpenAudioFrequencyInvertionPanel, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.CuttoffkhzEditFieldLabel = uilabel(app.OpenAudioFrequencyInvertionPanel);
            app.CuttoffkhzEditFieldLabel.Text = 'Cuttoff (khz)';
            app.CuttoffkhzEditField = uieditfield(app.OpenAudioFrequencyInvertionPanel, 'numeric');
            app.CuttoffkhzEditField.ValueChangedFcn = createCallbackFcn(app, @CuttoffkhzEditFieldValueChanged, true);
            app.HashAudioButton = uibutton(app.OpenAudioFrequencyInvertionPanel, 'push');
            app.HashAudioButton.ButtonPushedFcn = createCallbackFcn(app, @HashAudioButtonPushed, true);
            app.HashAudioButton.Text = 'Hash Audio';
            app.PlayAudioPanel = uipanel(app.HashTab);
            app.PlayAudioPanel.Title = 'Play Audio';
            app.InputAudioButton = uibutton(app.PlayAudioPanel, 'push');
            app.InputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @InputAudioButtonPushed, true);
            app.InputAudioButton.Text = 'Input Audio';
            app.ScrambeledAudioButton = uibutton(app.PlayAudioPanel, 'push');
            app.ScrambeledAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ScrambeledAudioButtonPushed, true);
            app.ScrambeledAudioButton.Text = 'Scrambeled Audio';
            app.SpectrumDiagramsPanel = uipanel(app.HashTab);
            app.SpectrumDiagramsPanel.Title = 'Spectrum Diagrams';
            app.UIAxes = uiaxes(app.SpectrumDiagramsPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.SpectrumDiagramsPanel);
            zlabel(app.UIAxes2, 'Z')
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.OpenScarmbeledAudioReconstructionPanel = uipanel(app.ReconstructionTab);
            app.OpenScarmbeledAudioReconstructionPanel.Title = 'Open Scarmbeled Audio & Reconstruction';
            app.SaveButton = uibutton(app.OpenScarmbeledAudioReconstructionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenHashAudioButton = uibutton(app.OpenScarmbeledAudioReconstructionPanel, 'push');
            app.OpenHashAudioButton.ButtonPushedFcn = createCallbackFcn(app, @OpenHashAudioButtonPushed, true);
            app.OpenHashAudioButton.Text = 'Open Hash Audio';
            app.CuttoffkhzEditField_2Label = uilabel(app.OpenScarmbeledAudioReconstructionPanel);
            app.CuttoffkhzEditField_2Label.Text = 'Cuttoff (khz)';
            app.CuttoffkhzEditField_2 = uieditfield(app.OpenScarmbeledAudioReconstructionPanel, 'numeric');
            app.CuttoffkhzEditField_2.ValueChangedFcn = createCallbackFcn(app, @CuttoffkhzEditField_2ValueChanged, true);
            app.ReconstructAudioButton = uibutton(app.OpenScarmbeledAudioReconstructionPanel, 'push');
            app.ReconstructAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructAudioButtonPushed, true);
            app.ReconstructAudioButton.Text = 'Reconstruct Audio';
            app.PlayAudioPanel_2 = uipanel(app.ReconstructionTab);
            app.PlayAudioPanel_2.Title = 'Play Audio';
            app.InputScarmbeledAudioButton = uibutton(app.PlayAudioPanel_2, 'push');
            app.InputScarmbeledAudioButton.ButtonPushedFcn = createCallbackFcn(app, @InputScarmbeledAudioButtonPushed, true);
            app.InputScarmbeledAudioButton.Text = 'Input Scarmbeled Audio';
            app.ReconstructedAudioButton = uibutton(app.PlayAudioPanel_2, 'push');
            app.ReconstructedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructedAudioButtonPushed, true);
            app.ReconstructedAudioButton.Text = 'Reconstructed Audio';
            app.SpectrumDiagramsPanel_2 = uipanel(app.ReconstructionTab);
            app.SpectrumDiagramsPanel_2.Title = 'Spectrum Diagrams';
            app.UIAxes_2 = uiaxes(app.SpectrumDiagramsPanel_2);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.SpectrumDiagramsPanel_2);
            zlabel(app.UIAxes2_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Q3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end